classdef RR_bot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        Theta1degreesEditFieldLabel    matlab.ui.control.Label
        Theta1degrees                  matlab.ui.control.NumericEditField
        ForwardKinematicsButton        matlab.ui.control.Button
        Link1SliderLabel               matlab.ui.control.Label
        Link1                          matlab.ui.control.Slider
        Link2SliderLabel               matlab.ui.control.Label
        Link2                          matlab.ui.control.Slider
        InverseKinematicsButton        matlab.ui.control.Button
        ClearFigureButton              matlab.ui.control.Button
        EndEffectorLabel               matlab.ui.control.Label
        SigmaSwitch                    matlab.ui.control.Switch
        WaitingLamp                    matlab.ui.control.Lamp
        Theta1degrees_2                matlab.ui.control.NumericEditField
        Theta1degreesEditFieldLabel_2  matlab.ui.control.Label
        XcoordinateEditFieldLabel_2    matlab.ui.control.Label
        Xcoordinate                    matlab.ui.control.NumericEditField
        YcoordinateEditFieldLabel      matlab.ui.control.Label
        Ycoordinate                    matlab.ui.control.NumericEditField
        SigmaLabel                     matlab.ui.control.Label
        WaitingLampLabel               matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: ForwardKinematicsButton
        function ForwardKinematicsButtonPushed(app, event)
            
            cla(app.UIAxes)
            
            theta1 = deg2rad(app.Theta1degrees.Value);
            theta2 = ((20*pi)/180)*sin(3*theta1+pi/3);
            app.Theta1degrees_2.Value = double(fix(rad2deg(theta2)));
            
            joints_angle = [theta1 theta2];
            link_len = [app.Link1.Value app.Link2.Value];
            
            end_effect_x = link_len(1)*cos(joints_angle(1))+link_len(2)*cos(joints_angle(1)+joints_angle(2));
            end_effect_y = link_len(1)*sin(joints_angle(1))+link_len(2)*sin(joints_angle(1)+joints_angle(2));
            
            app.Xcoordinate.Value = end_effect_x;
            app.Ycoordinate.Value = end_effect_y;
            
            grid (app.UIAxes, 'on')
            app.UIAxes.GridLineStyle = ':';
            xlim(app.UIAxes, [-(link_len(1)+link_len(2)+1) (link_len(1)+link_len(2)+1)])
            ylim(app.UIAxes, [-(link_len(1)+link_len(2)+1) (link_len(1)+link_len(2)+1)])
            
            plot(app.UIAxes, [0 link_len(1)*cos(theta1)], [0 link_len(1)*sin(theta1)], 'b', 'LineWidth', 4);
            plot(app.UIAxes, link_len(1)*cos(theta1), link_len(1)*sin(theta1),'or');
            plot(app.UIAxes, 0,0,'or');
            plot(app.UIAxes, end_effect_x, end_effect_y, 'or')
            plot(app.UIAxes, [link_len(1)*cos(theta1) end_effect_x], [link_len(1)*sin(theta1) end_effect_y], 'g', 'LineWidth', 4);
            
            app.WaitingLampLabel.Text = 'Solution Available';
            app.WaitingLamp.Color = 'green';
    
        end

        % Callback function
        function ClearFigureButtonPushed(app, event)

        end

        % Button pushed function: ClearFigureButton
        function ClearFigureButtonPushed2(app, event)
            cla(app.UIAxes)
            app.WaitingLampLabel.Text = 'Waiting...';
            app.WaitingLamp.Color = [0.65 0.65 0.65];
        end

        % Callback function
        function ClearFigureButton_2Pushed(app, event)

        end

        % Button pushed function: InverseKinematicsButton
        function InverseKinematicsButtonPushed(app, event)
            
            cla(app.UIAxes)
            
            link_len = [app.Link1.Value app.Link2.Value];
            L1 = link_len(1);
            L2 = link_len(2);
            
            X_e = app.Xcoordinate.Value;
            Y_e = app.Ycoordinate.Value;
            
            A = -2*X_e*L1;
            B = -2*Y_e*L1;
            C = X_e^2 + Y_e^2 + L1^2 - L2^2;
            
            sigma = app.SigmaSwitch.Value;
            check = (2*B)^2 - 4*(C-A)*(C+A);
            
            if  check>= 0
            
                if strcmp(sigma, 'Elbow Up (1)') == 1
                    x = -2*B + sqrt((2*B)^2 - 4*(C^2-A^2));
                    y = (2*(C-A));
                    theta_11 = 2*(atan2((-2*B + sqrt((2*B)^2 - 4*(C^2-A^2))), (2*(C-A))));
                    theta_21 = -theta_11 + atan2(Y_e - L1*sin(theta_11), X_e - L1*cos(theta_11));
                    theta_links = [theta_11 theta_21];
                    
                elseif strcmp(sigma, 'Elbow Down (-1)') == 1
                    theta_12 = 2*(atan2(-2*B - sqrt((2*B)^2 - 4*(C^2-A^2)), 2*(C-A)));
                    theta_22 = -theta_12 + atan2(Y_e - L1*sin(theta_12), X_e - L1*cos(theta_12));
                    theta_links = [theta_12 theta_22];
                    
                end
                app.Theta1degrees.Value = theta_links(1)*180/pi;
                app.Theta1degrees_2.Value = theta_links(2)*180/pi;
                grid (app.UIAxes, 'on')
                app.UIAxes.GridLineStyle = ':';
                xlim(app.UIAxes, [-(L1+L2+1) (L1+L2+1)])
                ylim(app.UIAxes, [-(L1+L2+1) (L1+L2+1)])
                
                plot(app.UIAxes, [0 L1*cos(theta_links(1))], [0 L1*sin(theta_links(1))], 'b', 'LineWidth', 4);
                plot(app.UIAxes, L1*cos(theta_links(1)), L1*sin(theta_links(1)),'or');
                plot(app.UIAxes, 0,0,'or');
                plot(app.UIAxes, X_e, Y_e, 'or')
                plot(app.UIAxes, [L1*cos(theta_links(1)) X_e], [L1*sin(theta_links(1)) Y_e], 'g', 'LineWidth', 4);
                
                app.WaitingLampLabel.Text = 'Solution Available';
                app.WaitingLamp.Color = 'green';
    
            else
                app.WaitingLampLabel.Text = 'No Solution';
                app.WaitingLamp.Color = 'red';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Task Space')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Theta1degreesEditFieldLabel = uilabel(app.UIFigure);
            app.Theta1degreesEditFieldLabel.Text = 'Theta 1 (degrees)';
            app.Theta1degrees = uieditfield(app.UIFigure, 'numeric');
            app.Theta1degrees.Limits = [-360 360];
            app.Theta1degrees.ValueDisplayFormat = '%.0f';
            app.ForwardKinematicsButton = uibutton(app.UIFigure, 'push');
            app.ForwardKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardKinematicsButtonPushed, true);
            app.ForwardKinematicsButton.Text = 'Forward Kinematics';
            app.Link1SliderLabel = uilabel(app.UIFigure);
            app.Link1SliderLabel.Text = 'Link 1';
            app.Link1 = uislider(app.UIFigure);
            app.Link1.Limits = [1 8];
            app.Link1.MinorTicks = [];
            app.Link1.Value = 3;
            app.Link2SliderLabel = uilabel(app.UIFigure);
            app.Link2SliderLabel.Text = 'Link 2';
            app.Link2 = uislider(app.UIFigure);
            app.Link2.Limits = [1 8];
            app.Link2.MinorTicks = [];
            app.Link2.Value = 2;
            app.InverseKinematicsButton = uibutton(app.UIFigure, 'push');
            app.InverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsButtonPushed, true);
            app.InverseKinematicsButton.Text = 'Inverse Kinematics';
            app.ClearFigureButton = uibutton(app.UIFigure, 'push');
            app.ClearFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFigureButtonPushed2, true);
            app.ClearFigureButton.Text = 'Clear Figure';
            app.EndEffectorLabel = uilabel(app.UIFigure);
            app.EndEffectorLabel.Text = 'End Effector:';
            app.SigmaSwitch = uiswitch(app.UIFigure, 'slider');
            app.SigmaSwitch.Items = {'Elbow Down (-1)', 'Elbow Up (1)'};
            app.SigmaSwitch.Value = 'Elbow Down (-1)';
            app.WaitingLamp = uilamp(app.UIFigure);
            app.Theta1degrees_2 = uieditfield(app.UIFigure, 'numeric');
            app.Theta1degrees_2.Limits = [-360 360];
            app.Theta1degrees_2.RoundFractionalValues = 'on';
            app.Theta1degrees_2.ValueDisplayFormat = '%.0f';
            app.Theta1degrees_2.Editable = 'off';
            app.Theta1degreesEditFieldLabel_2 = uilabel(app.UIFigure);
            app.Theta1degreesEditFieldLabel_2.Text = 'Theta 2 (degrees)';
            app.XcoordinateEditFieldLabel_2 = uilabel(app.UIFigure);
            app.XcoordinateEditFieldLabel_2.Text = 'X - coordinate';
            app.Xcoordinate = uieditfield(app.UIFigure, 'numeric');
            app.YcoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.YcoordinateEditFieldLabel.Text = 'Y - coordinate';
            app.Ycoordinate = uieditfield(app.UIFigure, 'numeric');
            app.SigmaLabel = uilabel(app.UIFigure);
            app.SigmaLabel.Text = 'Sigma:';
            app.WaitingLampLabel = uilabel(app.UIFigure);
            app.WaitingLampLabel.Text = 'Waiting...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RR_bot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end